classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        nguloconlahorizontalEditField  matlab.ui.control.NumericEditField
        nguloconlahorizontalEditFieldLabel  matlab.ui.control.Label
        VelocidadInicialEditField_2    matlab.ui.control.NumericEditField
        VelocidadInicialEditField_2Label  matlab.ui.control.Label
        CalcularButton                 matlab.ui.control.Button
        DatosLabel                     matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        MovimientoParablicoLabel       matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: CalcularButton, UIAxes
        function CalcularButtonPushed(app, event)
            cla(app.UIAxes, "reset");
            g = 9.8;
            vo = app.VelocidadInicialEditField_2.Value;
            teta = app.nguloconlahorizontalEditField.Value;
            teta = teta*pi/180;
            xmax = ((vo^2) * sin(teta)) / g;
            ymax = (vo * sin(teta)) ^ 2 / (2 * g);

            for i=0:0.5:xmax
                y3 = (tan(teta) * i) - (0.5 * g / (vo * cos(teta)) ^ 2) * (i ^ 2);
                hold on;
                pause(0.1);
                plot(app.UIAxes, i, y3, 'r--*', 'LineWidth',2)
                grid(app.UIAxes, "on");
                legend(app.UIAxes, 'Objeto en movimiento');
                axis([0 xmax 0 ymax]);
                title(app.UIAxes, 'MOVIMIENTO PARÁBOLICO OBJETO'), grid on,
                xlabel(app.UIAxes, 'Distancia Horizontal')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {245, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DatosLabel = uilabel(app.LeftPanel);
            app.DatosLabel.Text = 'Datos';
            app.CalcularButton = uibutton(app.LeftPanel, 'push');
            app.CalcularButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.CalcularButton.Text = 'Calcular ▒';
            app.VelocidadInicialEditField_2Label = uilabel(app.LeftPanel);
            app.VelocidadInicialEditField_2Label.Text = 'Velocidad Inicial';
            app.VelocidadInicialEditField_2 = uieditfield(app.LeftPanel, 'numeric');
            app.nguloconlahorizontalEditFieldLabel = uilabel(app.LeftPanel);
            app.nguloconlahorizontalEditFieldLabel.Text = {'Ángulo con la'; ' horizontal'};
            app.nguloconlahorizontalEditField = uieditfield(app.LeftPanel, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.RightPanel.Tag = 'axe';
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Gráfica')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @CalcularButtonPushed, true);
            app.UIAxes.Tag = 'axe';
            app.MovimientoParablicoLabel = uilabel(app.RightPanel);
            app.MovimientoParablicoLabel.Text = 'Movimiento Parabólico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end